classdef MatGPT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MatGPTUIFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        ChatGridLayout                  matlab.ui.container.GridLayout
        RecStatusLamp                   matlab.ui.control.Lamp
        RecStatusLampLabel              matlab.ui.control.Label
        RecordVoiceButton               matlab.ui.control.Button
        PaperClip                       matlab.ui.control.Image
        SuggestfollowupquestionsCheckBox  matlab.ui.control.CheckBox
        TestGeneratedMATLABCodeCheckBox  matlab.ui.control.CheckBox
        TabGroup2                       matlab.ui.container.TabGroup
        ChatTab                         matlab.ui.container.Tab
        ChatHTML                        matlab.ui.control.HTML
        UsageTab                        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        UsagedataisnotavailableinstreamingmodeLabel  matlab.ui.control.Label
        TocalculateAPIfeespleaseconsultLabel  matlab.ui.control.Label
        Hyperlink2                      matlab.ui.control.Hyperlink
        TotaltokensusedEditField        matlab.ui.control.NumericEditField
        TotaltokensusedEditFieldLabel   matlab.ui.control.Label
        PrompttokensusedEditField       matlab.ui.control.NumericEditField
        PrompttokensusedEditFieldLabel  matlab.ui.control.Label
        CompletiontokensusedEditField   matlab.ui.control.NumericEditField
        CompletiontokensusedEditFieldLabel  matlab.ui.control.Label
        PromptTextArea                  matlab.ui.control.TextArea
        ChatTree                        matlab.ui.container.Tree
        Logo                            matlab.ui.control.Image
        SendButton                      matlab.ui.control.Button
        MatGPTpoweredbyOpenAIChatGPTAPILabel  matlab.ui.control.Label
        SettingsTab                     matlab.ui.container.Tab
        SettingsGridLayout              matlab.ui.container.GridLayout
        ConnectionTimeoutLabel          matlab.ui.control.Label
        VoiceChatPanel                  matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        secsLabel_2                     matlab.ui.control.Label
        MaxDurationEditField            matlab.ui.control.NumericEditField
        MaxDurationEditFieldLabel       matlab.ui.control.Label
        LanguageDropDown                matlab.ui.control.DropDown
        LanguageDropDownLabel           matlab.ui.control.Label
        VoiceDropDown                   matlab.ui.control.DropDown
        VoiceDropDownLabel              matlab.ui.control.Label
        EnableVoiceChatCheckBox         matlab.ui.control.CheckBox
        UsagedataisnotavailableinstreamingmodeandDALLE3Label  matlab.ui.control.Label
        StreamResponseCheckBox          matlab.ui.control.CheckBox
        secsLabel                       matlab.ui.control.Label
        TimeoutEditField                matlab.ui.control.NumericEditField
        TimeoutEditFieldLabel           matlab.ui.control.Label
        KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel  matlab.ui.control.Label
        SaveKeyButton                   matlab.ui.control.Button
        YourOpenAIAPIKeyEditField       matlab.ui.control.EditField
        YourOpenAIAPIKeyEditFieldLabel  matlab.ui.control.Label
        APIKeyNotFoundLamp              matlab.ui.control.Lamp
        APIKeyNotFoundLampLabel         matlab.ui.control.Label
        PresetsListBox                  matlab.ui.control.ListBox
        PresetsListBoxLabel             matlab.ui.control.Label
        SystemPromptTextArea            matlab.ui.control.TextArea
        SystemPromptTextAreaLabel       matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel       matlab.ui.control.Label
        MaxTokensEditField              matlab.ui.control.NumericEditField
        MaxTokensEditFieldLabel         matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        ModelDropDownLabel              matlab.ui.control.Label
        ModelAttributesLabel            matlab.ui.control.Label
        SetOpenAIAPIKeyLabel            matlab.ui.control.Label
        OrcustomizeparametersyourselfLabel  matlab.ui.control.Label
        GetyourAPIkeyfromLabel          matlab.ui.control.Label
        ChooseapresetLabel              matlab.ui.control.Label
        StartNewChatButton              matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        moreprecise1balanced2morecreativeLabel  matlab.ui.control.Label
        MaximumnumberoftokenstoincludeinresponseLabel  matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        AdvancedTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        Label_6                         matlab.ui.control.Label
        Sequence4EditField              matlab.ui.control.EditField
        Label_5                         matlab.ui.control.Label
        Sequence3EditField              matlab.ui.control.EditField
        Label_4                         matlab.ui.control.Label
        Sequence2EditField              matlab.ui.control.EditField
        Label_3                         matlab.ui.control.Label
        Sequence1EditField              matlab.ui.control.EditField
        Label_2                         matlab.ui.control.Label
        StopSequencesLabel              matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        RenameMenu                      matlab.ui.container.Menu
        DeleteMenu                      matlab.ui.container.Menu
        SavetofileMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % presets
        Presets;
        % path of the app file
        Path;
        % holds most recent test report
        TestReport;
        % holds most recent error messages
        ErrorMessages;
        % image path
        Image;
        % tools
        Tools;
        % voice chat
        recObj
    end
    
    methods (Access = private)
                
        function resetChatHTML(app)
            % RESETCHATHTML - Reset the chat HTML source (happens whenever
            % switching to a previous chat or starting a new chat)
            app.ChatHTML.HTMLSource = "";
            app.ChatHTML.Data = [];
            drawnow
            app.ChatHTML.HTMLSource = fullfile(app.Path,"contents","index.html");
            drawnow
        end
        
        function addChatLog(app,role,content,nvp)
            % ADDCHATLOG - Add a row to the Chat History table by changing
            % the "Data" property. This triggers an event listener to update
            % the HTML.
            arguments
                app
                role    {mustBeTextScalar}
                content {mustBeTextScalar}
                nvp.Row {mustBeMember(nvp.Row,["new","current"])}
            end

            % Use most recent chat log if not specified
            if nargin<2
                data = app.ChatTree.SelectedNodes.NodeData;
                % extract role and content from Messages
                role = data.messages.Messages{end}.role;
                content = data.messages.Messages{end}.content;
            end
            if ismember(role,["assistant","MATLAB"])
                % convert markdown tables and code blocks to HTML
                htmlContent = TextHelper.replaceTableMarkdown(content);
                % convert ``` markdown from responses to our code style
                htmlContent = TextHelper.replaceCodeMarkdown(htmlContent);
                % Update the uihtml Data property to trigger the JavaScript 
                % listener that will add a row to the HTML table
                app.ChatHTML.Data = {role,htmlContent,nvp.Row};
            else
                app.ChatHTML.Data = {role,content,"new"};
            end
        end

        function showStartUpMessage(app)
            s = MsgHelper.startupMessages();
            for ii = 1:numel(s.content)
                addChatLog(app,s.role,s.content(ii));
                drawnow
                pause(1/20)
            end
        end

        function streamTokens(app,token)
        %STREAMTOKENS prints the streamed tokens in a new row in the table
            if strlength(token) == 0
                % if the first token is 0 length, add a new row
                tokens = string(token);
                app.ChatHTML.Data = {"assistant",tokens,"new"};
            else
                % otherwise append the new token to the previous tokens
                % if the new token contains a line break, replace 
                % it with <br>
                if contains(token,newline)
                    token = replace(token,newline,"<br>");
                end
                tokens = app.ChatHTML.Data{2} + string(token);
                % update the existing row. 
                app.ChatHTML.Data = {"assistant",tokens,"current"};
            end
            drawnow
        end
        
        function content = getContentFromURL(app, prompt)
        %GETCONTENTFROMURL retrieves content from the URL
            selectedNodes = app.ChatTree.SelectedNodes;
            bot = selectedNodes.NodeData.bot;

            % check if the prompt contains any url
            url = MsgHelper.extractURL(prompt);
            % if the url was given, get confirmation to access it
            if strlength(url)
                % check how many URLs were detected
                if numel(url) > 1
                    msg = "Multiple URLs were detected. Please use only one URL";
                    uialert(app.MatGPTUIFigure,msg,"Error: Too many URLs")
                    content = "";
                    return
                end
                % ask for confirmation
                msg = "Are you sure you want to load the content of " + ...
                    "this page in MATLAB? If the page is over the " + ...
                    "token limit, the content will be truncated.";
                ttl = "URL Detected";
                selection = uiconfirm(app.MatGPTUIFigure, msg + newline + newline + url, ...
                    ttl, Icon="warning");
                % if confirmed
                switch selection
                    % if user confirmed
                    case 'OK'
                        % read the page
                        try
                            page = webread(url);
                        catch ME
                            uialert(app.MatGPTUIFigure,ME.message,"Error");
                            content = 0;
                            return
                        end
                        % if the content is text
                        if ischar(page)
                        % extract HTML content
                            webContent = TextHelper.extractContentfromHTML(page);
                            % Add meta data
                            webContent = "The content of URL " + url + ": " + newline + webContent;
                            % make sure it fits the context window
                            max_tokens = app.MaxTokensEditField.Value;
                            content = MsgHelper.fitContextWindow(bot.ModelName,webContent,max_tokens);
                        elseif isa(page,'uint8')
                            content = url;
                        end
                    otherwise
                        content = "";
                end
            else
                content = "";
            end
            
        end
    
        function bot = createBot(app,params)
            isText = cellfun(@(x) (isa(x,"string")|isa(x,"char")),params);
            systemPrompt = params{1};
            isModelName = false(size(isText));
            isModelName(isText) = cellfun(@(x) contains(x,"ModelName"), params(isText));
            model = params{find(isModelName)+1};
            isTimeOut(isText) = cellfun(@(x) contains(x,"TimeOut"), params(isText));
            timeout = params{find(isTimeOut)+1};
            sfun = @(x)streamTokens(app,x);
            if model == "dall-e-3"
                f = openAIFunction("generateImage","Generates an image from a text prompt.");
                f = addParameter(f,"prompt",type="string",description="text prompt that describes the image to generate.");
                bot = openAIChat(systemPrompt,ModelName="gpt-4o-mini",Tools=f,TimeOut=timeout);
            else
                if app.StreamResponseCheckBox.Value
                    params = [params,{'StreamFun',sfun}];
                end
                bot = openAIChat(params{:});
            end
        end

        function toggleSettings(app)
            if app.ModelDropDown.Value == "dall-e-3"
                app.CompletiontokensusedEditField.Enable =" off";
                app.PrompttokensusedEditField.Enable = "off";
                app.TotaltokensusedEditField.Enable = "off";
                app.StreamResponseCheckBox.Value = 0;
                app.StreamResponseCheckBox.Enable = "off";
                app.TestGeneratedMATLABCodeCheckBox.Value = 0;
                app.TestGeneratedMATLABCodeCheckBox.Enable = "off";
                app.SuggestfollowupquestionsCheckBox.Value = 0;
                app.SuggestfollowupquestionsCheckBox.Enable = "off";
                app.Sequence1EditField.Enable ="off";
                app.Sequence2EditField.Enable ="off";
                app.Sequence3EditField.Enable ="off";
                app.Sequence4EditField.Enable ="off";
            else
                app.StreamResponseCheckBox.Enable = "on";
                app.TestGeneratedMATLABCodeCheckBox.Enable = "on";
                app.SuggestfollowupquestionsCheckBox.Enable = "on";
                app.Sequence1EditField.Enable ="on";
                app.Sequence2EditField.Enable ="on";
                app.Sequence3EditField.Enable ="on";
                app.Sequence4EditField.Enable ="on";
                if app.StreamResponseCheckBox.Value
                    app.CompletiontokensusedEditField.Enable =" off";
                    app.PrompttokensusedEditField.Enable = "off";
                    app.TotaltokensusedEditField.Enable = "off";
                else
                    app.CompletiontokensusedEditField.Enable =" on";
                    app.PrompttokensusedEditField.Enable = "on";
                    app.TotaltokensusedEditField.Enable = "on";
                end
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                %% load external files
                % get the path of the app file
                [app.Path,~,~] = fileparts(mfilename("fullpath"));
                % add path to helper classes and functions
                addpath(fullfile(app.Path,"helpers"));
                addpath(fullfile(app.Path,"helpers","llms-with-matlab"));
                % check if LLMs with MATLAB is available
                if isempty(which("openAIChat"))
                    err = "Please install the submodule 'LLMs with MATLAB'. See README.md for more details.";
                    uialert(app.MatGPTUIFigure,err,"Error");
                end
                % check if gpt-4o-mini is supported
                try
                    supportedModels = llms.openai.models;
                    if ~ismember("gpt-4o-mini",supportedModels)
                        ME = MException('llms.openai.models:noSuchModel','gpt-4o-mini not found');
                        throw(ME)
                    end
                catch
                    err = "To use gpt-4o-mini, update the submodule 'LLMs with MATLAB' to the latest";
                    uialert(app.MatGPTUIFigure,err,"Error");
                end
                % load presets from csv file
                app.Presets = readtable(fullfile(app.Path,"contents","presets.csv"), ...
                    TextType="string",Delimiter=",");
                % load chat history data
                chatHistoryFile = fullfile(app.Path,"contents","ChatData.mat");
                % Initialize the HTML files
                resetChatHTML(app)
                % display the startup message
                showStartUpMessage(app)
                
            catch ME
                % display error dialog
                uialert(app.MatGPTUIFigure,ME.message,"Error");
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                return
            end
            
            %% Populate various fields
            % add new chat node
            if isempty(app.ChatTree.Children)
                uitreenode(app.ChatTree,'Text','+ New Chat');
            end
            % append old chats to ChatTree
            if isfile(chatHistoryFile)
                load(chatHistoryFile,"PreviousChats")

                % Check if chat history is current
                isLegacy = arrayfun(@(x) isfield(x,'model'), PreviousChats.chat_history);
                if any(isLegacy)
                    uialert(app.MatGPTUIFigure, ...
                        "Legacy chat histry found" + newline + ...
                        "Save the legacy chats to text files.","Warning","Icon","warning");
                end

                % add old chats to ChatTree
                for ii = 1:height(PreviousChats)
                    topic = PreviousChats.topic(ii);
                    s = PreviousChats.chat_history(ii);
                    uitreenode(app.ChatTree,"NodeData",s,"Text",topic, ... 
                        "ContextMenu",app.ContextMenu);
                end
            end
            % Initialize Presets ListBox
            app.PresetsListBox.Items = app.Presets.name;
            app.PresetsListBox.ItemsData = 1:height(app.Presets);
            app.PresetsListBox.Value = 1;
            % set parameter defaults
            app.ModelDropDown.Value = app.Presets.model(1);
            app.MaxTokensEditField.Value = app.Presets.max_tokens(1);
            app.TemperatureEditField.Value = app.Presets.temperature(1);
            app.SystemPromptTextArea.Value = app.Presets.content(1);
            app.StreamResponseCheckBox.Value = app.Presets.streaming(1);
            % add model attributes
            model = app.ModelDropDown.Value;
            [cw,co] = MsgHelper.getModelDetails(model);
            str = "Context Window: " + cw + " tokens, Cutoff: " + co;
            app.ModelAttributesLabel.Text = str;
            
            % check the release
            release = str2double(extractBetween(version('-release'),1,4));
            isOnline = contains(userpath,"MATLAB Drive");
            if release >= 2024 && ~isOnline
                if isSecret("OPENAI_API_KEY")
                    apikey = getSecret("OPENAI_API_KEY");
                    setenv("OPENAI_API_KEY",apikey);
                else
                    apikey = '';
                end
                app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel.Text = "Key will be saved in MATLAB Vault as ""OPENAI_API_Key""";
                app.SaveKeyButton.Text = "Enter Key";
                app.YourOpenAIAPIKeyEditField.Enable = "off";
                app.YourOpenAIAPIKeyEditField.Visible = "off";
            else
                apikey = getenv("OPENAI_API_KEY");
            end
            if isempty(apikey)
                % disable Start New Chat button
                app.StartNewChatButton.Enable = "off";
            else
                % enable Start New Chat button
                app.StartNewChatButton.Enable = "on";
                app.APIKeyNotFoundLampLabel.Text = "API Key Found";
                app.APIKeyNotFoundLamp.Color = "Green";
            end
            % disable PaperClip image
            app.PaperClip.Enable = "off";
            % disable Record button 
            app.RecordVoiceButton.Enable = "off";
        end

        % Button pushed function: StartNewChatButton
        function StartNewChat(app, event)
            % set parameters
            model = string(app.ModelDropDown.Value);
            systemPrompt = join(string(app.SystemPromptTextArea.Value),newline);
            temperature = app.TemperatureEditField.Value;
            
            % get stop sequences
            stop = cell(1,4);
            stop{1} = app.Sequence1EditField.Value;
            stop{2} = app.Sequence2EditField.Value;
            stop{3} = app.Sequence3EditField.Value;
            stop{4} = app.Sequence4EditField.Value;
            stop(cellfun(@isempty,stop)) = [];

            % enable PaperClip image
            app.PaperClip.Enable = "on";

            % standard params
            params = {systemPrompt,'ModelName',model,'Temperature',temperature};
            % add stop sequence if exist
            if ~isempty(stop)
                params = [params,{'StopSequences',string(stop)}];
            end
            % get timeout
            timeout = app.TimeoutEditField.Value;
            if ~isempty(timeout)
                params = [params,{'TimeOut',timeout}];
            end

            try
                % instantiate the openAIChat class
                bot = createBot(app,params);
            catch ME
                % display error dialog
                uialert(app.MatGPTUIFigure,ME.message,"Error");
            end
            
            % add new node to the tree
            nodeLabels = arrayfun(@(x) string(x.Text), app.ChatTree.Children);
            myChats = nodeLabels(contains(nodeLabels,"My Chat"));
            if isempty(myChats)
                n = 0;
            else
                myChats = erase(myChats,["💬 My Chat "," ...✏️"]);
                n = max(str2double(myChats));
            end
            % construct the node data
            s = struct('bot',bot,'messages',openAIMessages);
            s.params = params;
            s.streaming = app.StreamResponseCheckBox.Value;
            uitreenode(app.ChatTree, "NodeData",s, ...
                "Text","💬 My Chat " + (n + 1) + " ...✏️", ...
                "ContextMenu",app.ContextMenu);
            % move the current chat to the second place
            app.ChatTree.Children = [app.ChatTree.Children(1);app.ChatTree.Children(end); ... 
                app.ChatTree.Children(2:end-1)];
            app.ChatTree.SelectedNodes = app.ChatTree.Children(2);
            % Intialize the HTML files
            resetChatHTML(app)
            % Initialize the chat log - this will just shows the "system" message for now
            if model ~= "dall-e-3"
                addChatLog(app,"system",systemPrompt)
            end

            % if voice chat is enabled
            if app.EnableVoiceChatCheckBox.Value
                % initialize recObj
                Fs = 44100;
                nBits = 16; 
                nChannels = 1;
                app.recObj = audiorecorder(Fs, nBits, nChannels);
                app.RecordVoiceButton.Enable = "on";
            end
            
            app.TabGroup.SelectedTab = app.TabGroup.Children(1);
            % update UI components
            app.SendButton.Enable = "on";
            n = app.PresetsListBox.Value;
            app.SuggestfollowupquestionsCheckBox.Value = app.Presets.suggested_questions(n);
            app.PromptTextArea.Value = app.Presets.prompt(n);

        end

        % Button pushed function: SendButton
        function send(app, event)
            % get current selection
            selectedNodes = app.ChatTree.SelectedNodes;
            if isempty(selectedNodes.NodeData.bot)
                bot = createBot(app,selectedNodes.params);
            else
                bot = selectedNodes.NodeData.bot;
            end
            messages = selectedNodes.NodeData.messages;

            % get user prompt
            prompt = string(app.PromptTextArea.Value);
            if prompt == ""
                uialert(app.MatGPTUIFigure, ...
                        "Please provide a prompt.","Error");
                return
            elseif contains(prompt,"Select a file using the paper clip icon on the left.")
                uialert(app.MatGPTUIFigure, ...
                        "Please select a file.","Error");
                return
            else
                prompt = join(prompt,newline);
            end

            % extract content from the URL in the prompt
            extractedContent = getContentFromURL(app, prompt);
            if ~isstring(extractedContent)
                return
            elseif startsWith(extractedContent,["http://","https://"]) && endsWith(extractedContent,[".jpg",".png",".gif"])
                % image url
                app.Image = extractedContent;
            elseif strlength(extractedContent)
                % add content as function message
                messages = MsgHelper.addFunctionMessage(messages,extractedContent);
                role = "function";
                addChatLog(app,role,extractedContent);
                drawnow
            end

            % check if user wants follow-up questions suggested
            if app.SuggestfollowupquestionsCheckBox.Value
                relatedQ = "After answering this question, suggest 3 related questions after 'Three related questions: '";
                prompt = prompt + newline + newline + relatedQ;
            end
            % get max tokens parameter
            max_tokens = app.MaxTokensEditField.Value;

            % if streaming is not enabled
            if ~app.StreamResponseCheckBox.Value
                % display progress dialog box
                progressdlg = uiprogressdlg(app.MatGPTUIFigure,"Title","Please Wait", ...
                    "Message","Waiting for API Response...", ...
                    "Indeterminate","on");
            end

            % if user didn't selected an image
            if isempty(app.Image)
                % add the user prompt to Messages
                messages = addUserMessage(messages,prompt);
                % extract messages struct and add to HTML
                s = messages.Messages;
                addChatLog(app,s{end}.role,s{end}.content);
            % if user selected an image
            else
                % add the user prompt to Messages
                if ismember(bot.ModelName,["gpt-4o-mini","gpt-4o-mini-2024-07-18","gpt-4-turbo","gpt-4-turbo-2024-04-09","gpt-4o","gpt-4o-2024-05-13"])
                    messages = addUserMessageWithImages(messages,prompt, app.Image);
                else
                    messages = addUserMessage(messages,prompt);
                end
                % extract messages struct and add to HTML
                s = messages.Messages;
                role = s{end}.role;
                if isa(s{end}.content{1},"struct")
                    content = s{end}.content{1}.text;
                else
                    content = s{end}.content;
                end
                % append the image url
                if startsWith(app.Image,["http://","https://"])
                    content = content + "<br><img width=512 src='" + app.Image + "'>";
                else
                    content = content + "<br>" + MsgHelper.getHTMLImgTag(app.Image);
                end
                % add to the table
                addChatLog(app,role,content);
            end
            drawnow
            pause(1/20)
            
            % send the prompt to API
            try
                % remove test reports before sending user prompt
                if ~isempty(bot.FunctionNames) && bot.FunctionNames == "generateImage"
                    [image,m,r] = imageGenGenerate(bot,messages);
                    timestamp = string(datetime('now','Format','yyyy-MM-dd''T''HH-mm-SS'));
                    imgpath = fullfile(app.Path,"contents","GeneratedImages");
                    if ~isfolder(imgpath)
                        mkdir(imgpath)
                    end
                    imgfile = fullfile(imgpath,"dalle3_" + timestamp + ".png");
                    imwrite(image,imgfile,"png");
                    response = "";
                    m = struct("role", "assistant", "content", jsonencode(struct("text",m.content,"image_file",imgfile)));
                    imgTag = MsgHelper.getHTMLImgTag(imgfile);
                else
                    noTestMessages = MsgHelper.removeTestReports(messages);
                    [response,m,r] = generate(bot,noTestMessages,MaxNumTokens=max_tokens);
                end
            catch ME
                uialert(app.MatGPTUIFigure,ME.message,"Error " + ME.identifier);
                if contains(ME.message, "Check your API key.")
                    app.APIKeyNotFoundLampLabel.Text = 'API Key Not Valid';
                    app.APIKeyNotFoundLamp.Color = 'Red';
                    app.StartNewChatButton.Enable = "off";
                    app.ChatTree.SelectedNodes.delete;
                    app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                end
                return
            end
            
            % check if you have the response
            if ~isempty(fieldnames(m))
                % add the response to Messages
                messages = addResponseMessage(messages,m);
            else
                err = r.Body.Data.error;
                if ~isempty(err.code)
                    uialert(app.MatGPTUIFigure,err.message,string(err.type) + ...
                        ": " + err.code);
                else
                    uialert(app.MatGPTUIFigure,err.message,string(err.type) + ...
                        ": []");
                end
                return
            end

            % add messages to the current chat
            selectedNodes.NodeData.messages = messages;

            s = messages.Messages;
            % extract text from json
            if contains(s{end}.content,"image_file" + wildcardPattern + ".png")
                c = jsondecode(s{end}.content);
                s{end}.content = c.text;
            end
            
            % check if related questions are contained in the response
            if contains(response,"Three related questions:")
                % turn related questions into buttons
                answer = MsgHelper.processRelatedQuestions(response);
                % check if imageUrl exist
                if exist('imgTag','var')
                    addChatLog(app,s{end}.role,answer + "<br>" + imgTag,Row="current");
                else
                    addChatLog(app,s{end}.role,answer,Row="current");
                end
            else
                % check if imageUrl exist
                if exist('imgTag','var')
                    addChatLog(app,s{end}.role,s{end}.content + "<br>" + imgTag,Row="new");
                else
                    addChatLog(app,s{end}.role,s{end}.content,Row="current");
                end
            end
            % if voice chat is enabled
            if app.EnableVoiceChatCheckBox.Value
                voice = string(app.VoiceDropDown.Value);
                if exist("answer","var")
                    voiceOver = extractBefore(answer,'<button class="relquestion">');
                else
                    voiceOver = string(s{end}.content);
                end
                [y,Fs,~] = openAIAudio.speech(voiceOver,Voice=voice);
                sound(y,Fs);
            end
            drawnow

            % clear the prompt
            app.PromptTextArea.Value = "";
            % clear the image
            app.Image = [];

            % if streaming is not enabled
            if ~app.StreamResponseCheckBox.Value && ~isempty(bot.FunctionNames) && bot.FunctionNames ~= "generateImage"
                % display the tokens used and cost
                usage = r.Body.Data.usage;
                app.CompletiontokensusedEditField.Value = app.CompletiontokensusedEditField.Value ...
                    + usage.completion_tokens;
                app.PrompttokensusedEditField.Value = app.PrompttokensusedEditField.Value ...
                    + usage.prompt_tokens;
                app.TotaltokensusedEditField.Value = app.TotaltokensusedEditField.Value + ...
                    usage.total_tokens;
            end

            % If Validated Generated MATLAB code checkbox is checked 
            app.ErrorMessages = {};
            if app.TestGeneratedMATLABCodeCheckBox.Value

                % if streaming is enabled
                if app.StreamResponseCheckBox.Value
                    % display progress dialog box
                    progressdlg = uiprogressdlg(app.MatGPTUIFigure,"Title","Please Wait", ...
                        "Message","Testing code...", ...
                        "Indeterminate","on");
                else
                    % Upate the progress dialog
                    progressdlg.Message = progressdlg.Message + " Done" + newline + "Testing code... ";
                end
                
                % set up code checker
                checker = CodeChecker(response);
                % run checks
                runChecks(checker); 
                app.ErrorMessages = checker.ErrorMessages;
                % add test report to chat log
                addChatLog(app,"MATLAB",checker.Report,Row="new");
                drawnow
                % add test report to Messages as function message
                messages = MsgHelper.addFunctionMessage(messages,checker.Report);
                % update ChatTree data
                selectedNodes.NodeData.messages = messages;
            end
            
            % if progress dialog is open
            if exist('progressdlg','var')
                % close the progress bar
                progressdlg.Message = [progressdlg.Message ' Done'];
                progressdlg.Title = 'Done';
                close(progressdlg);
                clear progressdlg
            end
        end

        % Value changed function: PresetsListBox
        function setPreset(app, event)
            n = app.PresetsListBox.Value;
            app.ModelDropDown.Value = app.Presets.model(n);
            ModelDropDownValueChanged(app, event)
            app.MaxTokensEditField.Value = app.Presets.max_tokens(n);
            app.TemperatureEditField.Value = app.Presets.temperature(n);
            app.SystemPromptTextArea.Value = app.Presets.content(n);
            app.PromptTextArea.Value = app.Presets.prompt(n);
            app.TestGeneratedMATLABCodeCheckBox.Value = app.Presets.test_code(n);
            app.SuggestfollowupquestionsCheckBox.Value = app.Presets.suggested_questions(n);
            app.StreamResponseCheckBox.Value = app.Presets.streaming(n);
            toggleSettings(app)
        end

        % Selection changed function: ChatTree
        function ChatTreeSelectionChanged(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            % if New Chat
            if selectedNodes == app.ChatTree.Children(1)
                app.TabGroup.SelectedTab = app.TabGroup.Children(2);
                resetChatHTML(app);
                app.StreamResponseCheckBox.Enable = "on";
                drawnow
            % if previous chat
            else
                % Reset the HTML table            
                resetChatHTML(app)

                % the check if the hisotry is current
                if isfield(selectedNodes.NodeData,'model')
                    uialert(app.MatGPTUIFigure,"Legacy chat history." + ...
                        newline + "Double click and save chat to file.","Warning",Icon="warning");
                    return
                end
                
                % extract node data
                if isempty(selectedNodes.NodeData.bot)
                    bot = createBot(app,selectedNodes.NodeData.params);
                else
                    bot = selectedNodes.NodeData.bot;
                end
                messages = selectedNodes.NodeData.messages;
                app.StreamResponseCheckBox.Value = selectedNodes.NodeData.streaming;

                % iterate over messages
                for ii = 1:numel(messages.Messages)
                    s = messages.Messages{ii};
                    content = s.content;
                    if isempty(content)
                        continue
                    else
                        if isstring(content) && s.role ~= "user" && contains(content,"Three related questions:")
                            content = MsgHelper.processRelatedQuestions(content);
                        elseif iscell(content)
                            imgUrl = content{2}.image_url.url;
                            content = content{1}.text;
                            % append the image url
                            content = content + "<br><img width=512 src='" + imgUrl + "'>";
                        elseif ~isempty(bot.FunctionNames) && bot.FunctionNames == "generateImage"
                            if contains(content,"image_file" + wildcardPattern + ".png")
                                c = jsondecode(content);
                                imgTag = MsgHelper.getHTMLImgTag(c.image_file);
                                content = c.text + "<br>" + imgTag;
                            end
                        end

                        addChatLog(app,s.role,content,Row="new")
                        drawnow
                        pause(1/20)
                    end
                end
                app.SendButton.Enable = "on";
                app.PaperClip.Enable = "on";
            end
        end

        % Menu selected function: RenameMenu
        function RenameMenuSelected(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            prompt = 'Enter new name';
            dlgtitle = 'Rename Chat';
            definput = selectedNodes.Text;
            definput = cellstr(erase(definput,["💬 ","...✏️"]));
            answer = string(inputdlg(prompt,dlgtitle,[1 40],definput));
            if answer ~= ""
                selectedNodes.Text = "💬 " + answer + "...✏️";
            end
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            selectedNodes = app.ChatTree.SelectedNodes;
            if isempty(selectedNodes)
                prompt = 'Please select a chat to delete.';
                dlgtitle = 'No chat selected';
                questdlg(prompt,dlgtitle,'Cancel','Cancel');
            else
                prompt = 'Are you sure you want to delete the chat?';
                dlgtitle = 'Confirm your choice';
                answer = questdlg(prompt,dlgtitle,'Delete','Cancel','Cancel');
                switch answer
                    case 'Delete'            
                        selectedNodes.delete;
                        resetChatHTML(app);
                        drawnow
                        app.PromptTextArea.Value = "";
                        app.SendButton.Enable = "off";
                end
            end
        end

        % Menu selected function: SavetofileMenu
        function SaveChatMenuSelected(app, event)
            filename = uiputfile('chat history.txt');
            if ~isequal(filename,0)
                selectedNodes = app.ChatTree.SelectedNodes;
                messages = selectedNodes.NodeData.messages;
      
                if isfield(selectedNodes.NodeData,"model")
                    % legacy chat
                    roles = arrayfun(@(x) string(x.role) , messages)';
                    contents = arrayfun(@(x) string(x.content), messages)';
                else
                    % new chat
                    roles = cellfun(@(x) string(x.role), messages.Messages)';
                    contents = cellfun(@(x) string(x.content), messages.Messages)';
                end
                str = pad(roles,max(strlength(roles))) + ": " + contents;
                writelines(str,filename)
            end
        end

        % Close request function: MatGPTUIFigure
        function MatGPTUIFigureCloseRequest(app, event)
            chats = app.ChatTree.Children(2:end);
            topics = arrayfun(@(x) string(x.Text), chats);
            % check if chat tree includes legacy chats
            isLegacy = arrayfun(@(x) isfield(x.NodeData,'model'), chats);
            msg = "Legacy chat history found: ";
            legacyTopics = topics(isLegacy);
            for ii = 1:numel(legacyTopics)
                msg = msg + newline + legacyTopics(ii);
            end
            msg = msg + newline + newline + "1) Double click and save chats to file" + ...
                newline + "2) Delete chats from the chat history.";

            if ~isempty(isLegacy) && all(isLegacy)                
                YN = uiconfirm(app.MatGPTUIFigure,"You have legacy chat history." + ...
                    newline + newline + 'Do you want to close the app?', 'Close request');
                if strcmp(YN,'OK')
                    delete(app)
                else
                    uialert(app.MatGPTUIFigure,msg,"Warning","Icon","warning");
                end
            else
                if any(isLegacy)
                    answer = questdlg("Closing App overwrites legacy chats with new chats. Proceed?","Closing App","Yes","Cancel","Cancel");
                    switch answer
                        case "Cancel"
                            uialert(app.MatGPTUIFigure,msg,"Warning", Icon="warning");
                            return
                    end
                end
                newChats = chats(~isLegacy);
                chat_history = arrayfun(@(x) x.NodeData, newChats);
                chat_history = arrayfun(@(x) setfield(x,'bot',[]), chat_history);
                PreviousChats = table;
                PreviousChats.topic = topics(~isLegacy);
                PreviousChats.chat_history = chat_history;
                % make sure the message object contains at least 3 messages
                isValid = arrayfun(@(x) numel(x.messages.Messages), PreviousChats.chat_history) >= 2;
                PreviousChats(~isValid,:) = [];
                chatHistoryFile = fullfile(app.Path,"contents","ChatData.mat");
                % if there is any messages left
                if numel(PreviousChats.topic) > 0
                    save(chatHistoryFile,"PreviousChats")
                else
                    if isfile(chatHistoryFile)
                        delete(chatHistoryFile);
                    end
                end
                delete(app)
            end
        end

        % Button pushed function: SaveKeyButton
        function SaveKeyButtonPushed(app, event)
            % check the release
            release = str2double(extractBetween(version('-release'),1,4));
            isOnline = contains(userpath,"MATLAB Drive");
            if release >= 2024 && ~isOnline
                try
                    if isSecret("OPENAI_API_KEY")
                        setSecret("OPENAI_API_KEY",Overwrite=true);
                    else
                        setSecret("OPENAI_API_KEY");
                    end
                catch ME
                    if string(ME.identifier) == "MATLAB:authnz:secretapis:SecretPromptValueNotProvided"
                        msg = "API key was not provided.";
                        uialert(app.MatGPTUIFigure,msg,"Warning", Icon="warning");
                    end
                end
                if isSecret("OPENAI_API_KEY")
                    setenv("OPENAI_API_KEY",getSecret("OPENAI_API_KEY"));
                    app.StartNewChatButton.Enable = "on";
                    app.APIKeyNotFoundLampLabel.Text = "API Key Found";
                    app.APIKeyNotFoundLamp.Color = "Green";
                end
            else
                apikey = app.YourOpenAIAPIKeyEditField.Value;
                setenv("OPENAI_API_KEY",apikey);
                if ~isempty(apikey)
                    app.YourOpenAIAPIKeyEditField.Value = "";
                    app.StartNewChatButton.Enable = "on";
                    app.APIKeyNotFoundLampLabel.Text = "API Key Found";
                    app.APIKeyNotFoundLamp.Color = "Green";
                end
            end
        end

        % Double-clicked callback: ChatTree
        function ChatTreeDoubleClicked(app, event)
            node = event.InteractionInformation.Node;
            location = event.InteractionInformation.Location;
            cm = node.ContextMenu;
            open(cm,location)
        end

        % Image clicked function: PaperClip
        function PaperClipClicked(app, event)
            % get the current chat            
            selectedNodes = app.ChatTree.SelectedNodes;
            % the current bot
            bot = selectedNodes.NodeData.bot;
            if isempty(selectedNodes.NodeData.messages)
                % instantiate the openAIMessages class
                messages = openAIMessages;
            else
                % extract previsous messages
                messages = selectedNodes.NodeData.messages;
            end

            % supported file types
            fileTypes = ["*.m;*.mlx", ... 
                "Source files (*.m,*.mlx)"];
            fileTypes = [fileTypes; ["*.csv;*.txt", ...
                "Data files (*.csv,*.txt)"]];
            % add PDF if Text Analytics Toolbox is available
            if license('test','Text_Analytics_Toolbox')
                fileTypes = [fileTypes; ["*.pdf", ...
                    "PDF files (*.pdf)"]];
            end
            % for GPT-4 Turbo Vision
            if ismember(bot.ModelName,["gpt-4o-mini","gpt-4o-mini-2024-07-18","gpt-4o","gpt-4o-2024-05-13","gpt-4-turbo","gpt-4-turbo-2024-04-09"])
                fileTypes = [fileTypes; ["*.jpg;*.png;*.gif", ...
                    "Image files (*.jpg,*.png,*.gif)"]];
            end
            
            % open the file selection dialog
            [filename,path,~] = uigetfile( ...
                fileTypes,'Select a file');
            filepath = string(fullfile(path,filename));
            % if user select a file
            if filename ~= 0
                if endsWith(filename,[".jpg",".png",".gif"])
                    im = imread(filepath);
                    [height,width,~] = size(im);
                    if max([height,width]) <= 2000 && min([height,width]) <= 768
                        % add image
                        app.Image = filepath;
                        % update the prompt
                        app.PromptTextArea.Value = "What is in this image?" +  " 📎" + filename;
                    else
                    uialert(app.MatGPTUIFigure,"Image size exceeds the limit (max 2000x768 or 768x2000)","Error");
                    end
                else
                    % check the file size
                    s = dir(filepath);
                    % return error if it exceeds the limit
                    if s.bytes > 5.12e+8
                        uialert(app.MatGPTUIFigure,"File size exceeds the limit (< 512MB)","Error");
                        return
                    end
                    % if live script was chosen
                    if endsWith(filename,".mlx")
                        currentpath = pwd;
                        outpath = fullfile(string(currentpath),filename);
                        outpath = replace(outpath,".mlx",".m");
                        export(filepath,outpath)
                        fileContent = readlines(outpath);
                    % if PDF was chosen
                    elseif endsWith(filename,".pdf")
                        fileContent = extractFileText(filepath);
                    % if m-file, csv or txt file
                    else
                        fileContent = readlines(filepath);
                    end
                    % create a text scalar by joining rows 
                    fileContent = fileContent + newline;
                    fileContent = join(fileContent);
    
                    % make sure it fits the context window
                    max_tokens = app.MaxTokensEditField.Value;
                    fittedContent = MsgHelper.fitContextWindow(bot.ModelName,fileContent,max_tokens);
    
                    % add appropriate HTML tags based on the file type
                    if endsWith(filename,[".m",".mlx"])
                        fittedContent = "<code>" + fittedContent + "</code>";
                    else
                        fittedContent = "<pre>" + fittedContent + "</pre>";
                    end
        
                    % add the file content to Messages
                    prompt = "File " + filename + " content: " + newline + newline;
                    prompt = prompt + fittedContent;
                    messages = MsgHelper.addFunctionMessage(messages,prompt);
                    selectedNodes.NodeData.messages = messages;
        
                    % update chat HTML
                    role = 'function';
                    content = prompt;
                    addChatLog(app,role,content);
                    drawnow
                    % update the prompt
                    app.PromptTextArea.Value = "What does this file contain?" +  " 📎" + filename;
                end
            end
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            model = app.ModelDropDown.Value;
            [cw,co] = MsgHelper.getModelDetails(model);
            str = "Context Window: " + cw + " tokens, Cutoff: " + co;
            app.ModelAttributesLabel.Text = str;
            toggleSettings(app)
        end

        % Data changed function: ChatHTML
        function ChatHTMLDataChanged(app, event)
            data = app.ChatHTML.Data;
            app.PromptTextArea.Value = data;
        end

        % Value changed function: StreamResponseCheckBox
        function StreamResponseCheckBoxValueChanged(app, event)
            toggleSettings(app)
            if app.StreamResponseCheckBox.Value
                app.UsagedataisnotavailableinstreamingmodeandDALLE3Label.Visible = "on";
            else
                app.UsagedataisnotavailableinstreamingmodeandDALLE3Label.Visible = "off";
            end
        end

        % Button pushed function: RecordVoiceButton
        function RecordVoiceButtonPushed(app, event)
            if strcmp(app.RecordVoiceButton.Text,"Record Voice")
	            recDuration = app.MaxDurationEditField.Value;
                record(app.recObj, recDuration);
                app.RecordVoiceButton.Text = "Stop & Transcribe";
                app.RecStatusLamp.Enable ="on";
                app.RecStatusLamp.Visible ="on";
                app.RecStatusLamp.Color = "red";
            else
                stop(app.recObj);
                app.RecordVoiceButton.Text = "Record Voice";
                app.RecStatusLamp.Enable ="off";
                app.RecStatusLamp.Visible ="off";
                app.RecStatusLamp.Color = "white";
                y = getaudiodata(app.recObj);
                timenow = string(datetime('now','Format','uuuuMMdd''T''HHmmss'));
                filename = "rec_" +  timenow + '.wav';
                filename  = fullfile(app.Path,"contents",filename);
                Fs = 44100;
                audiowrite(filename, y, Fs);
                language = string(app.LanguageDropDown.Value);
                output = openAIAudio.transcriptions(filename,Language=language);
                app.PromptTextArea.Value = string(output.text);
                voice = string(app.VoiceDropDown.Value);
                [y,Fs,~] = openAIAudio.speech(string(output.text),Voice=voice);
                sound(y,Fs);
                delete(filename);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MatGPTUIFigure = uifigure('Visible', 'off');
            app.MatGPTUIFigure.Name = 'MatGPT';
            app.MatGPTUIFigure.CloseRequestFcn = createCallbackFcn(app, @MatGPTUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MatGPTUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ChatGridLayout = uigridlayout(app.MainTab);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel = uilabel(app.ChatGridLayout);
            app.MatGPTpoweredbyOpenAIChatGPTAPILabel.Text = 'MatGPT powered by OpenAI™ ChatGPT API';
            app.SendButton = uibutton(app.ChatGridLayout, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @send, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = 'Send';
            app.Logo = uiimage(app.ChatGridLayout);
            app.Logo.ImageSource = fullfile(pathToMLAPP, 'images', 'MatGPTlogo.png');
            app.ChatTree = uitree(app.ChatGridLayout);
            app.ChatTree.SelectionChangedFcn = createCallbackFcn(app, @ChatTreeSelectionChanged, true);
            app.ChatTree.DoubleClickedFcn = createCallbackFcn(app, @ChatTreeDoubleClicked, true);
            app.PromptTextArea = uitextarea(app.ChatGridLayout);
            app.TabGroup2 = uitabgroup(app.ChatGridLayout);
            app.ChatTab = uitab(app.TabGroup2);
            app.ChatTab.Title = 'Chat';
            app.ChatHTML = uihtml(app.ChatTab);
            app.ChatHTML.DataChangedFcn = createCallbackFcn(app, @ChatHTMLDataChanged, true);
            app.UsageTab = uitab(app.TabGroup2);
            app.UsageTab.Title = 'Usage';
            app.GridLayout = uigridlayout(app.UsageTab);
            app.CompletiontokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.CompletiontokensusedEditFieldLabel.Text = 'Completion tokens used';
            app.CompletiontokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.CompletiontokensusedEditField.Editable = 'off';
            app.PrompttokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.PrompttokensusedEditFieldLabel.Text = 'Prompt tokens used';
            app.PrompttokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.PrompttokensusedEditField.Editable = 'off';
            app.TotaltokensusedEditFieldLabel = uilabel(app.GridLayout);
            app.TotaltokensusedEditFieldLabel.Text = 'Total tokens used';
            app.TotaltokensusedEditField = uieditfield(app.GridLayout, 'numeric');
            app.TotaltokensusedEditField.Editable = 'off';
            app.Hyperlink2 = uihyperlink(app.GridLayout);
            app.Hyperlink2.URL = 'https://openai.com/pricing';
            app.Hyperlink2.Text = 'OPENAI pricing page';
            app.TocalculateAPIfeespleaseconsultLabel = uilabel(app.GridLayout);
            app.TocalculateAPIfeespleaseconsultLabel.Text = 'To calculate API fees, please consult';
            app.UsagedataisnotavailableinstreamingmodeLabel = uilabel(app.GridLayout);
            app.UsagedataisnotavailableinstreamingmodeLabel.Text = 'Usage data is not available in streaming mode. ';
            app.TestGeneratedMATLABCodeCheckBox = uicheckbox(app.ChatGridLayout);
            app.TestGeneratedMATLABCodeCheckBox.Text = 'Test Generated MATLAB Code';
            app.SuggestfollowupquestionsCheckBox = uicheckbox(app.ChatGridLayout);
            app.SuggestfollowupquestionsCheckBox.Text = 'Suggest follow-up questions';
            app.SuggestfollowupquestionsCheckBox.Value = true;
            app.PaperClip = uiimage(app.ChatGridLayout);
            app.PaperClip.ImageClickedFcn = createCallbackFcn(app, @PaperClipClicked, true);
            app.PaperClip.Enable = 'off';
            app.PaperClip.ImageSource = fullfile(pathToMLAPP, 'images', 'paperclip.png');
            app.RecordVoiceButton = uibutton(app.ChatGridLayout, 'push');
            app.RecordVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @RecordVoiceButtonPushed, true);
            app.RecordVoiceButton.Text = 'Record Voice';
            app.RecStatusLampLabel = uilabel(app.ChatGridLayout);
            app.RecStatusLampLabel.Enable = 'off';
            app.RecStatusLampLabel.Visible = 'off';
            app.RecStatusLampLabel.Text = 'Rec Status';
            app.RecStatusLamp = uilamp(app.ChatGridLayout);
            app.RecStatusLamp.Enable = 'off';
            app.RecStatusLamp.Visible = 'off';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsGridLayout = uigridlayout(app.SettingsTab);
            app.Hyperlink = uihyperlink(app.SettingsGridLayout);
            app.Hyperlink.URL = 'https://platform.openai.com/api-keys';
            app.Hyperlink.Text = 'https://platform.openai.com/api-keys';
            app.MaximumnumberoftokenstoincludeinresponseLabel = uilabel(app.SettingsGridLayout);
            app.MaximumnumberoftokenstoincludeinresponseLabel.Text = 'Maximum number of tokens to include in response';
            app.moreprecise1balanced2morecreativeLabel = uilabel(app.SettingsGridLayout);
            app.moreprecise1balanced2morecreativeLabel.Text = '0 = more precise, 1 = balanced, 2 = more creative ';
            app.Label = uilabel(app.SettingsGridLayout);
            app.Label.Text = 'Describe the role you assign to the chatbot. The default is an AI assistant.';
            app.StartNewChatButton = uibutton(app.SettingsGridLayout, 'push');
            app.StartNewChatButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewChat, true);
            app.StartNewChatButton.Enable = 'off';
            app.StartNewChatButton.Text = 'Start New Chat';
            app.ChooseapresetLabel = uilabel(app.SettingsGridLayout);
            app.ChooseapresetLabel.Text = 'Choose a preset';
            app.GetyourAPIkeyfromLabel = uilabel(app.SettingsGridLayout);
            app.GetyourAPIkeyfromLabel.Text = 'Get your API key from ';
            app.OrcustomizeparametersyourselfLabel = uilabel(app.SettingsGridLayout);
            app.OrcustomizeparametersyourselfLabel.Text = 'Or customize parameters yourself';
            app.SetOpenAIAPIKeyLabel = uilabel(app.SettingsGridLayout);
            app.SetOpenAIAPIKeyLabel.Text = 'Set OpenAI API Key';
            app.ModelAttributesLabel = uilabel(app.SettingsGridLayout);
            app.ModelAttributesLabel.Text = 'Model Attributes';
            app.ModelDropDownLabel = uilabel(app.SettingsGridLayout);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SettingsGridLayout);
            app.ModelDropDown.Items = {'gpt-4o-mini', 'gpt-4o-mini-2024-07-18', 'gpt-3.5-turbo', 'gpt-3.5-turbo-0125', 'gpt-3.5-turbo-1106', 'gpt-4o', 'gpt-4-turbo', 'gpt-4-turbo-2024-04-09', 'gpt-4', 'gpt-4-0613', 'dall-e-3'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Value = 'gpt-4o-mini';
            app.MaxTokensEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.MaxTokensEditFieldLabel.Text = 'Max Tokens';
            app.MaxTokensEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.MaxTokensEditField.Limits = [1 Inf];
            app.MaxTokensEditField.Value = 1000;
            app.TemperatureEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TemperatureEditFieldLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TemperatureEditField.Limits = [0 Inf];
            app.TemperatureEditField.Value = 1;
            app.SystemPromptTextAreaLabel = uilabel(app.SettingsGridLayout);
            app.SystemPromptTextAreaLabel.Text = 'System Prompt';
            app.SystemPromptTextArea = uitextarea(app.SettingsGridLayout);
            app.PresetsListBoxLabel = uilabel(app.SettingsGridLayout);
            app.PresetsListBoxLabel.Text = 'Presets';
            app.PresetsListBox = uilistbox(app.SettingsGridLayout);
            app.PresetsListBox.Items = {};
            app.PresetsListBox.ValueChangedFcn = createCallbackFcn(app, @setPreset, true);
            app.PresetsListBox.Value = {};
            app.APIKeyNotFoundLampLabel = uilabel(app.SettingsGridLayout);
            app.APIKeyNotFoundLampLabel.Text = 'API Key Not Found';
            app.APIKeyNotFoundLamp = uilamp(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.YourOpenAIAPIKeyEditFieldLabel.Text = 'Your OpenAI API Key';
            app.YourOpenAIAPIKeyEditField = uieditfield(app.SettingsGridLayout, 'text');
            app.SaveKeyButton = uibutton(app.SettingsGridLayout, 'push');
            app.SaveKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SaveKeyButtonPushed, true);
            app.SaveKeyButton.Text = 'Save Key';
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel = uilabel(app.SettingsGridLayout);
            app.KeywillbesavedinenvironmentvariableOPENAI_API_KeyLabel.Text = 'Key will be saved in environment variable "OPENAI_API_Key"';
            app.TimeoutEditFieldLabel = uilabel(app.SettingsGridLayout);
            app.TimeoutEditFieldLabel.Text = 'Timeout';
            app.TimeoutEditField = uieditfield(app.SettingsGridLayout, 'numeric');
            app.TimeoutEditField.Value = 10;
            app.secsLabel = uilabel(app.SettingsGridLayout);
            app.secsLabel.Text = 'secs';
            app.StreamResponseCheckBox = uicheckbox(app.SettingsGridLayout);
            app.StreamResponseCheckBox.ValueChangedFcn = createCallbackFcn(app, @StreamResponseCheckBoxValueChanged, true);
            app.StreamResponseCheckBox.Text = 'Stream Response';
            app.StreamResponseCheckBox.Value = true;
            app.UsagedataisnotavailableinstreamingmodeandDALLE3Label = uilabel(app.SettingsGridLayout);
            app.UsagedataisnotavailableinstreamingmodeandDALLE3Label.Text = 'Usage data is not available in streaming mode and DALL-E3. ';
            app.VoiceChatPanel = uipanel(app.SettingsGridLayout);
            app.VoiceChatPanel.Title = 'Voice Chat';
            app.GridLayout3 = uigridlayout(app.VoiceChatPanel);
            app.EnableVoiceChatCheckBox = uicheckbox(app.GridLayout3);
            app.EnableVoiceChatCheckBox.Text = 'Enable Voice Chat';
            app.VoiceDropDownLabel = uilabel(app.GridLayout3);
            app.VoiceDropDownLabel.Text = 'Voice';
            app.VoiceDropDown = uidropdown(app.GridLayout3);
            app.VoiceDropDown.Items = {'alloy', 'echo', 'fable', 'onyx', 'nova'};
            app.VoiceDropDown.Value = 'alloy';
            app.LanguageDropDownLabel = uilabel(app.GridLayout3);
            app.LanguageDropDownLabel.Text = 'Language';
            app.LanguageDropDown = uidropdown(app.GridLayout3);
            app.LanguageDropDown.Items = {'English', 'Spanish', 'German', 'French', 'Italian', 'Chinese', 'Japanese', 'Korean'};
            app.LanguageDropDown.ItemsData = {'en', 'es', 'de', 'fr', 'it', 'zh', 'ja', 'ko'};
            app.LanguageDropDown.Value = 'en';
            app.MaxDurationEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxDurationEditFieldLabel.Text = 'Max Duration';
            app.MaxDurationEditField = uieditfield(app.GridLayout3, 'numeric');
            app.MaxDurationEditField.Value = 30;
            app.secsLabel_2 = uilabel(app.GridLayout3);
            app.secsLabel_2.Text = 'secs';
            app.ConnectionTimeoutLabel = uilabel(app.SettingsGridLayout);
            app.ConnectionTimeoutLabel.Text = 'Connection Timeout';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.GridLayout2 = uigridlayout(app.AdvancedTab);
            app.StopSequencesLabel = uilabel(app.GridLayout2);
            app.StopSequencesLabel.Text = 'Stop Sequences';
            app.Label_2 = uilabel(app.GridLayout2);
            app.Label_2.Text = ' Sequence 1';
            app.Sequence1EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = ' Sequence 2';
            app.Sequence2EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_4 = uilabel(app.GridLayout2);
            app.Label_4.Text = ' Sequence 3';
            app.Sequence3EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_5 = uilabel(app.GridLayout2);
            app.Label_5.Text = 'Sequence 4';
            app.Sequence4EditField = uieditfield(app.GridLayout2, 'text');
            app.Label_6 = uilabel(app.GridLayout2);
            app.Label_6.Text = 'Specify the sequences where the API will stop generating further tokens';
            app.ContextMenu = uicontextmenu(app.MatGPTUIFigure);
            app.RenameMenu = uimenu(app.ContextMenu);
            app.RenameMenu.MenuSelectedFcn = createCallbackFcn(app, @RenameMenuSelected, true);
            app.RenameMenu.Text = 'Rename';
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            app.SavetofileMenu = uimenu(app.ContextMenu);
            app.SavetofileMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveChatMenuSelected, true);
            app.SavetofileMenu.Text = 'Save to file';
            % Show the figure after all components are created
            app.MatGPTUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatGPT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MatGPTUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MatGPTUIFigure)
        end
    end
end